classdef EEC201_proj_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ChooseAudioFileButton        matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        SelectedAudioEditFieldLabel  matlab.ui.control.Label
        SelectedAudioEditField       matlab.ui.control.EditField
        PlayoriginalaudiofileButton  matlab.ui.control.Button
        RecordAudioButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        FileName % Imported Audio
        Fs % Imported Sample Rate
        x % song after audio read. used for sound().
        recordedVoice
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function ImportWAVfileEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ChooseAudioFileButton
        function ChooseAudioFileButtonPushed(app, event)
    [app.FileName, PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
    [app.x, app.Fs] = audioread([PathName , app.FileName]);
    
    
    % displaying song title
    answer = app.FileName;
    app.SelectedAudioEditField.Value = answer;
    
    % changing samples to seconds
    Ts = 1/app.Fs;
    n = 0:length(app.x)-1;
    seconds = Ts*n;
    plot(app.UIAxes,seconds,app.x)
    %xticks(app.UIAxes,[0 
    
    % spectrogram
    window=kaiser(1200,5.48);
    nooverlap=1000;
    nfft=1024;
    [S,F,T] = spectrogram(app.x,window,nooverlap,nfft,app.Fs,'yaxis');
    surf(app.UIAxes2,T, F, 10*log10(abs(S)),'edgecolor','none'); 
    %view(2);
    
    %imagesc(app.UIAxes2, T, F, log(1+abs(S)) ); %plot the log spectrum
    %axis xy; axis tight; colormap(jet); view(0,0);
    
        end

        % Button pushed function: PlayoriginalaudiofileButton
        function PlayoriginalaudiofileButtonPushed(app, event)
            sound(app.x,app.Fs)
        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            recorder = audiorecorder;
  %          record(recorder)
  %          app.recordedVoice = getaudiodata(recorder);
            recordblocking(recorder, 5);
            play(recorder)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChooseAudioFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseAudioFileButtonPushed, true);
            app.ChooseAudioFileButton.Text = 'Choose Audio File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spectrogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SelectedAudioEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedAudioEditFieldLabel.Text = 'Selected Audio';
            app.SelectedAudioEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedAudioEditField.Editable = 'off';
            app.PlayoriginalaudiofileButton = uibutton(app.UIFigure, 'push');
            app.PlayoriginalaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalaudiofileButtonPushed, true);
            app.PlayoriginalaudiofileButton.Text = 'Play original audio file';
            app.RecordAudioButton = uibutton(app.UIFigure, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEC201_proj_APP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end